classdef AppFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FiltraggiodelsegnaleUIFigure  matlab.ui.Figure
        FrequenzaditaglioSliderLabel  matlab.ui.control.Label
        FrequenzaditaglioSlider       matlab.ui.control.Slider
        signalAxes                    matlab.ui.control.UIAxes
        ConfermaButton                matlab.ui.control.Button
        specAxes                      matlab.ui.control.UIAxes
        FiltraggiodelsegnaleLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        calling_app % oggetto della finestra chiamante
        
        signal      % dati grezzi importati (vettore colonna)
        signal_filt % segnale filtrato
        is_angles   % flag per trattare il segnale come angolare
        
        fcut        % frequenza di taglio normalizzata
        fbarmax     % soglia massima del filtro
        
        F           % trasformata di Fourier del segnale
        F_real      % parte reale di F
    end
    
    methods (Access = private)
        
        % calcola il segnale filtrato dalla porzione di trasformata di Fourier selezionata
        function updateFilteredSignal(app)
            cut = app.fcut;      % Posizione del taglio dello spettro
            len = length(app.F); % Ampiezza dello spettro doppio
            dim = floor(len/2);  % Ampiezza dello spettro singolo
            
            % DFT sinistra
            S_filt_1 = zeros(dim, 1);
            S_filt_1(1:cut,1) = app.F(1:cut, 1);
            
            % DFT destra
            S_filt_2 = zeros(dim, 1);
            S_filt_2(end-cut:end,1) = app.F(end-cut:end, 1);
            
            % DFT segnale filtrato
            S_filt = [S_filt_1; S_filt_2];
            
            %signal_reconstructed = ifft(S); % Antitrasformata del segnale originale
            filtered_reconstructed = ifft(S_filt); % Antitrasformata del segnale filtrato
            filtered_reconstructed = real(filtered_reconstructed); % Segnale filtarto
            
            app.signal_filt = filtered_reconstructed;
        end
        
        % disenga entrambi i plot (segnale e spettro)
        function makePlots(app)
            cla(app.signalAxes)
            cla(app.specAxes)
            
            % Plot Segnale originale e filtrato
            hold(app.signalAxes, 'on')
            plot(app.signalAxes, app.signal)
            plot(app.signalAxes, app.signal_filt,'LineWidth',2)
            hold(app.signalAxes, 'off')
            
            % Plot dello spettro doppio
            hold(app.specAxes, 'on')
            bar(app.specAxes, abs(app.F_real))
            plot(app.specAxes, [app.fcut app.fcut], [0 max(abs(app.F_real))]) % Plot fcut
            grid(app.specAxes, 'on')
            app.specAxes.XLim = [0, app.fbarmax];
            app.specAxes.YLim = [0, max(app.F_real)];
            hold(app.specAxes, 'off')
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app, signal, is_angles, fcut)
            if nargin == 1 % per debug
                app.calling_app = 0;
                app.signal = evalin('base', 'sens_B_orig(:,2)');
                app.is_angles = true;
            else
                app.calling_app = calling_app;
                app.signal = signal;
                app.is_angles = is_angles;
            end
            
            % se il segnale è angolare occorre sottrargli 2π ogni volta la differenza tra ogni
            % valore ed il precedente risulta prossima a 2π (implementato come > 3/2 π)
            if app.is_angles
                for i=2:length(app.signal)
                    if app.signal(i) - app.signal(i-1) > 3/2*pi
                        for j=i:length(app.signal)
                            app.signal(j) = app.signal(j) - 2*pi;
                        end
                    else
                        if app.signal(i) - app.signal(i-1) < -3/2*pi
                            for j=i:length(app.signal)
                                app.signal(j) = app.signal(j) + 2*pi;
                            end
                        end
                    end
                end
            end
            
            app.F = fft(app.signal); % DFT del segnale
            
            app.F_real = real(app.F); % Parte reale del segnale trasformato
            
            fbarmin = 1;                           % minimo valore dello slider
            app.fbarmax = floor(length(app.F)/40); % massimo valore dello slider
            app.FrequenzaditaglioSlider.Limits = [fbarmin, app.fbarmax];
            
            % se viene passata anche una frequenza di taglio imposta quella, altrimenti prendila come 1/10 della f massima
            if nargin == 5
                app.fcut = fcut;
            else
                app.fcut = round(app.fbarmax/10);
            end
            app.FrequenzaditaglioSlider.Value = app.fcut;
            
            updateFilteredSignal(app)
            makePlots(app)
        end

        % Value changed function: FrequenzaditaglioSlider
        function FrequenzaditaglioSliderValueChanged(app, event)
            app.fcut = round(app.FrequenzaditaglioSlider.Value);
            
            updateFilteredSignal(app)
            makePlots(app)
        end

        % Button pushed function: ConfermaButton
        function ConfermaButtonPushed(app, event)
            FiltraggiodelsegnaleUIFigureCloseRequest(app, event) % stesso effetto di chiudere la finestra
        end

        % Close request function: FiltraggiodelsegnaleUIFigure
        function FiltraggiodelsegnaleUIFigureCloseRequest(app, event)
            % restituisce il segnale filtrato alla finestra chiamante (AppSuddivisioneAutomatica)
            if app.calling_app ~= 0
                getReturnAppFilter(app.calling_app, app.signal_filt, app.fcut)
            end
            
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FiltraggiodelsegnaleUIFigure = uifigure;
            app.FiltraggiodelsegnaleUIFigure.Name = 'Filtraggio del segnale';
            app.FiltraggiodelsegnaleUIFigure.CloseRequestFcn = createCallbackFcn(app, @FiltraggiodelsegnaleUIFigureCloseRequest, true);
            app.FrequenzaditaglioSliderLabel = uilabel(app.FiltraggiodelsegnaleUIFigure);
            app.FrequenzaditaglioSliderLabel.Text = 'Frequenza di taglio';
            app.FrequenzaditaglioSlider = uislider(app.FiltraggiodelsegnaleUIFigure);
            app.FrequenzaditaglioSlider.ValueChangedFcn = createCallbackFcn(app, @FrequenzaditaglioSliderValueChanged, true);
            app.FrequenzaditaglioSlider.Value = 1;
            app.signalAxes = uiaxes(app.FiltraggiodelsegnaleUIFigure);
            title(app.signalAxes, 'Segnale originale e filtrato')
            xlabel(app.signalAxes, 'Tempo')
            ylabel(app.signalAxes, 'Ampiezza')
            app.ConfermaButton = uibutton(app.FiltraggiodelsegnaleUIFigure, 'push');
            app.ConfermaButton.ButtonPushedFcn = createCallbackFcn(app, @ConfermaButtonPushed, true);
            app.ConfermaButton.Text = 'Conferma';
            app.specAxes = uiaxes(app.FiltraggiodelsegnaleUIFigure);
            title(app.specAxes, 'Spettro')
            xlabel(app.specAxes, 'Frequenza')
            ylabel(app.specAxes, 'Ampiezza')
            app.FiltraggiodelsegnaleLabel = uilabel(app.FiltraggiodelsegnaleUIFigure);
            app.FiltraggiodelsegnaleLabel.Text = 'Filtraggio del segnale';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AppFilter(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FiltraggiodelsegnaleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FiltraggiodelsegnaleUIFigure)
        end
    end
end