classdef AppIdentificazioneOutliers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IdentificazioneoutliersUIFigure  matlab.ui.Figure
        DatidelsensorePanel         matlab.ui.container.Panel
        dalworkspaceDropDownLabel   matlab.ui.control.Label
        segnaleDropDown             matlab.ui.control.DropDown
        ricaricaButton              matlab.ui.control.Button
        lavariabiledeveessereunamatricen_samplesx2tempivaloriLabel  matlab.ui.control.Label
        plotSensore                 matlab.ui.control.UIAxes
        plotDeriv                   matlab.ui.control.UIAxes
        SogliaSliderLabel           matlab.ui.control.Label
        SogliaSlider                matlab.ui.control.Slider
        SogliaField                 matlab.ui.control.NumericEditField
        OutliersSuccessiviCheckBox  matlab.ui.control.CheckBox
        salvanelworkspaceButton     matlab.ui.control.Button
        apriplotButton              matlab.ui.control.Button
        trattacomevaloriangolariinCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        sens_data   % dati grezzi importati [n_samples x 2]
        is_angles   % flag per trattare il segnale come angolare
        outliersSuccessiviEnabled % flag per consentire il rilevamento di outliers successivi
        
        der         % derivata del segnale
        soglia      % valore per la derivata oltre il quale il dato è considerato outlier
        
        outliers_id % vettore di indici dei campioni ritenuti outliers
        outliers    % vettore dei valori dei campioni indicati da outliers_id
    end
    
    methods (Access = private)
        
        % popola il menù a tendina dal workspace
        function dropDownCaricaVariabili(app)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars)+[1,0]);
            cell_array{1} = 'seleziona variabile...';
            for i=2:length(vars)+1
                cell_array{i} = vars(i-1).name;
            end
            app.segnaleDropDown.Items = cell_array;
        end
        
        % abilita/disabilita gli input
        function inputsEnable(app, is_enable)
            if is_enable
                app.SogliaSlider.Enable = 'on';
                app.SogliaField.Enable = 'on';
                app.OutliersSuccessiviCheckBox.Enable = 'on';
                app.apriplotButton.Enable = 'on';
                app.salvanelworkspaceButton.Enable = 'on';
            else
                app.SogliaSlider.Enable = 'off';
                app.SogliaField.Enable = 'off';
                app.OutliersSuccessiviCheckBox.Enable = 'off';
                app.apriplotButton.Enable = 'off';
                app.salvanelworkspaceButton.Enable = 'off';
            end
        end
        
        % plot di destra
        function plotSensEOutliers(app)
            hold(app.plotSensore, 'on')
            cla(app.plotSensore) % pulisce il grafico
            plot(app.plotSensore, app.sens_data(app.outliers_id,1), app.outliers, '*') % outliers
            plot(app.plotSensore, app.sens_data(:,1), app.sens_data(:,2), '.') % sensore
            hold(app.plotSensore, 'off')
            app.plotSensore.XLim = [app.sens_data(1,1) app.sens_data(end,1)];
            
            %             figure
            %             hold on
            %             plot(app.sens_data(app.outliers_id,1), app.outliers, '*') % outliers
            %             plot(app.sens_data(:,1), app.sens_data(:,2)) % sensore
        end
        
        % plot di sinistra
        function plotDerESoglie(app)
            hold(app.plotDeriv, 'on')
            cla(app.plotDeriv) % pulisce il grafico
            time = app.sens_data(1:length(app.der),1);
            plot(app.plotDeriv, time, abs(app.der)) % derivata seconda
            plot(app.plotDeriv, [time(1,1) time(end,1)], [app.soglia app.soglia], 'k') % soglia
            hold(app.plotDeriv, 'off')
            app.plotDeriv.XLim = [time(1,1) time(end,1)];
            app.plotDeriv.YLim = app.SogliaSlider.Limits; % riduce le ordinate ai limiti imposti sulla soglia
        end
        
        % valuta quali dati siano outlier secondo la soglia impostata
        function identificaOutliers(app)
            app.outliers_id = [];
            
            peaks = abs(app.der) > app.soglia; % identifica quali sample hanno valore sopra soglia
            
            i = 1; % progressivo per la raccolta degli outliers
            j = 1; % progressivo per scorrere i samples
            while j < length(peaks)
                % nota: per ogni outlier si trovano due picchi nella derivata
                if peaks(j) == 1 % se il sample j è oltre soglia
                    % se non consenti outliers successivi considera solo le coppie di picchi
                    if app.outliersSuccessiviEnabled == false && peaks(j+1) == 1
                        app.outliers_id(i) = j + 1; % pongo l'indice successivo come outlier
                        i = i + 1;
                    end
                    
                    % Se il successivo valore della derivata non è di picco, significa che si è
                    % incontrato una serie di outliers consegutivi.
                    % (per n outlier successivi i due picchi sono distanziati da (n-1) non-picchi)
                    % Nonostante questo, può anche essere possibile che in realtà il secondo picco
                    % ci fosse, ma di poco sotto la soglia e quindi non correttamente identificato.
                    
                    if app.outliersSuccessiviEnabled == true
                        app.outliers_id(i) = j + 1; % pongo l'indice successivo come outlier
                        i = i + 1;
                        
                        % se la derivata del campione successivo è ampia quasi (>90%) quanto
                        % l'attuale, allora anche tale valore era un picco
                        while j < length(peaks) && (peaks(j+1) == 0 && abs(app.der(j+1)) / abs(app.der(j)) < .90)
                            j = j + 1;
                            app.outliers_id(i) = j + 1;
                            i = i + 1;
                        end
                    end
                    j = j + 2;
                else
                    j = j + 1;
                end
            end
            app.outliers = app.sens_data(app.outliers_id,2);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            inputsEnable(app, false)
            
            app.outliersSuccessiviEnabled = true;
            app.is_angles = false;
            
            dropDownCaricaVariabili(app)
        end

        % Value changed function: segnaleDropDown
        function segnaleDropDownValueChanged(app, event)
            varName = app.segnaleDropDown.Value;
            
            if strcmp(varName, 'seleziona variabile...')
                cla(app.plotSensore) % pulisce il grafico
                cla(app.plotDeriv)
                
                inputsEnable(app, false) % disabilita lo slider
                
            else % variabile selezionata
                % TODO: controlla che la variabile abbia il formato corretto
                
                % la variabile deve essere una matrice n_samples x 2: [tempi, valori]
                app.sens_data = evalin('base', varName);
                
                % tempi riferiti al primo campione
                %                 app.sens_data(:,1) = app.sens_data(:,1) - app.sens_data(1,1);
                
                % calcolo derivata: se il segnale è angolare occorre sottrarre 2π ad
                % ogni differenza che risulta prossima a 2π (implementato come > 3/2 π)
                app.der = diff(app.sens_data(:,2));
                if app.is_angles
                    for i=1:length(app.der)
                        if app.der(i) > 3/2*pi
                            app.der(i) = app.der(i) - 2*pi;
                        else
                            if app.der(i) < -3/2*pi
                                app.der(i) = app.der(i) + 2*pi;
                            end
                        end
                    end
                end
                
                % calcolo soglia
                soglia_max = mean(abs(app.der)) * 10;
                app.soglia = soglia_max / 2;
                app.SogliaSlider.Limits = [0 soglia_max];
                app.SogliaSlider.Value = app.soglia;
                app.SogliaField.Value = app.soglia;
                
                identificaOutliers(app)
                
                plotSensEOutliers(app)
                plotDerESoglie(app)
                
                inputsEnable(app, true) % abilita lo slider
            end
        end

        % Button pushed function: ricaricaButton
        function ricaricaButtonPushed(app, event)
            dropDownCaricaVariabili(app)
        end

        % Value changed function: SogliaSlider
        function SogliaSliderValueChanged(app, event)
            app.soglia = app.SogliaSlider.Value;
            app.SogliaField.Value = app.soglia;
            
            identificaOutliers(app)
            
            plotDerESoglie(app)
            plotSensEOutliers(app)
        end

        % Value changed function: SogliaField
        function SogliaFieldValueChanged(app, event)
            new_soglia = app.SogliaField.Value;
            
            if new_soglia < max(app.SogliaSlider.Limits)
                app.SogliaSlider.Value = new_soglia;
                SogliaSliderValueChanged(app, event)
            end
        end

        % Value changed function: OutliersSuccessiviCheckBox
        function OutliersSuccessiviCheckBoxValueChanged(app, event)
            app.outliersSuccessiviEnabled = app.OutliersSuccessiviCheckBox.Value;
            
            identificaOutliers(app)
            
            plotDerESoglie(app)
            plotSensEOutliers(app)
        end

        % Button pushed function: salvanelworkspaceButton
        function salvanelworkspaceButtonPushed(app, event)
            % si creano due array, uno di soli dati ed uno di soli outliers (inserendovi degli NaN)
            only_data = app.sens_data(:,2);
            only_data(app.outliers_id) = NaN;
            only_outliers = app.sens_data(:,2);
            data_id = ~ismember(1:length(only_outliers), app.outliers_id); % indici dei non outliers
            only_outliers(data_id) = NaN;
            
            var_names = inputdlg({'Dati ripuliti:','Outliers:'}, 'Salva nel workspace', 1, {'dati_ripuliti','outliers'});
            assignin('base', var_names{1}, only_data);
            assignin('base', var_names{2}, only_outliers);
        end

        % Button pushed function: apriplotButton
        function apriplotButtonPushed(app, event)
            figure('Name','Dati ed outliers')
            hold on
            plot(app.sens_data(app.outliers_id,1), app.outliers, '*') % outliers
            plot(app.sens_data(:,1), app.sens_data(:,2), '.') % sensore
            hold off
        end

        % Value changed function: trattacomevaloriangolariinCheckBox
        function trattacomevaloriangolariinCheckBoxValueChanged(app, event)
            app.is_angles = app.trattacomevaloriangolariinCheckBox.Value;
            
            segnaleDropDownValueChanged(app, event)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IdentificazioneoutliersUIFigure = uifigure;
            app.IdentificazioneoutliersUIFigure.Name = 'Identificazione outliers';
            app.DatidelsensorePanel = uipanel(app.IdentificazioneoutliersUIFigure);
            app.DatidelsensorePanel.Title = 'Dati del sensore';
            app.dalworkspaceDropDownLabel = uilabel(app.DatidelsensorePanel);
            app.dalworkspaceDropDownLabel.Text = 'dal workspace';
            app.segnaleDropDown = uidropdown(app.DatidelsensorePanel);
            app.segnaleDropDown.Items = {'seleziona variabile...', 'Option 2', 'Option 3', 'Option 4'};
            app.segnaleDropDown.ValueChangedFcn = createCallbackFcn(app, @segnaleDropDownValueChanged, true);
            app.segnaleDropDown.Value = 'seleziona variabile...';
            app.ricaricaButton = uibutton(app.DatidelsensorePanel, 'push');
            app.ricaricaButton.ButtonPushedFcn = createCallbackFcn(app, @ricaricaButtonPushed, true);
            app.ricaricaButton.Text = 'ricarica';
            app.lavariabiledeveessereunamatricen_samplesx2tempivaloriLabel = uilabel(app.DatidelsensorePanel);
            app.lavariabiledeveessereunamatricen_samplesx2tempivaloriLabel.Text = 'la variabile deve essere una matrice n_samples x 2: [tempi, valori]';
            app.plotSensore = uiaxes(app.IdentificazioneoutliersUIFigure);
            title(app.plotSensore, 'Segnale ed outliers')
            xlabel(app.plotSensore, 'Tempo relativo [s]')
            ylabel(app.plotSensore, 'Ampiezza')
            app.plotDeriv = uiaxes(app.IdentificazioneoutliersUIFigure);
            title(app.plotDeriv, 'Abs. della derivata')
            xlabel(app.plotDeriv, 'Tempo relativo [s]')
            ylabel(app.plotDeriv, 'Ampiezza')
            app.SogliaSliderLabel = uilabel(app.IdentificazioneoutliersUIFigure);
            app.SogliaSliderLabel.Text = 'Soglia';
            app.SogliaSlider = uislider(app.IdentificazioneoutliersUIFigure);
            app.SogliaSlider.ValueChangedFcn = createCallbackFcn(app, @SogliaSliderValueChanged, true);
            app.SogliaField = uieditfield(app.IdentificazioneoutliersUIFigure, 'numeric');
            app.SogliaField.ValueChangedFcn = createCallbackFcn(app, @SogliaFieldValueChanged, true);
            app.OutliersSuccessiviCheckBox = uicheckbox(app.IdentificazioneoutliersUIFigure);
            app.OutliersSuccessiviCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutliersSuccessiviCheckBoxValueChanged, true);
            app.OutliersSuccessiviCheckBox.Text = 'Identifica outliers successivi (può selezionare erroneamente l''intero segnale)';
            app.OutliersSuccessiviCheckBox.Value = true;
            app.salvanelworkspaceButton = uibutton(app.IdentificazioneoutliersUIFigure, 'push');
            app.salvanelworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @salvanelworkspaceButtonPushed, true);
            app.salvanelworkspaceButton.Text = 'salva nel workspace';
            app.apriplotButton = uibutton(app.IdentificazioneoutliersUIFigure, 'push');
            app.apriplotButton.ButtonPushedFcn = createCallbackFcn(app, @apriplotButtonPushed, true);
            app.apriplotButton.Text = 'apri plot';
            app.trattacomevaloriangolariinCheckBox = uicheckbox(app.IdentificazioneoutliersUIFigure);
            app.trattacomevaloriangolariinCheckBox.ValueChangedFcn = createCallbackFcn(app, @trattacomevaloriangolariinCheckBoxValueChanged, true);
            app.trattacomevaloriangolariinCheckBox.Text = 'tratta come valori angolari in [-π, π]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AppIdentificazioneOutliers

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IdentificazioneoutliersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IdentificazioneoutliersUIFigure)
        end
    end
end