classdef AppIndiveri < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SuddivisionedatiUIFigure      matlab.ui.Figure
        DatidelsensorePanel           matlab.ui.container.Panel
        dalworkspaceDropDownLabel     matlab.ui.control.Label
        segnaleDropDown               matlab.ui.control.DropDown
        ricaricaButton                matlab.ui.control.Button
        lavariabiledeveessereunamatricen_samplesx2tempivaloriLabel  matlab.ui.control.Label
        plotSensore                   matlab.ui.control.UIAxes
        IdentificazionesuddivisioniPanel  matlab.ui.container.Panel
        plotDeriv                     matlab.ui.control.UIAxes
        SogliaSliderLabel             matlab.ui.control.Label
        SogliaSlider                  matlab.ui.control.Slider
        GeneradivisionisuidatiButton  matlab.ui.control.Button
        SogliaField                   matlab.ui.control.NumericEditField
        plotFit                       matlab.ui.control.UIAxes
        LeastSquareButton             matlab.ui.control.Button
        LeastEntropyLikeButton        matlab.ui.control.Button
        Norma1Button                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        sens_data %segnale
        der_seconda %derivata seconda del segnale
        soglia %soglia che determina il numero di suddivisioni
        all_peaks_i %indici usati per suddividere il segnale sulla base dei picchi della derivata seconda
        Mr % matrice di regressione
        thetaLS % soluzione ai minimi quadrati
        thetaN1 % soluzione norma 1
    end
    
    methods (Access = private)
        
        function dropDownCaricaVariabili(app)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars)+[1,0]);
            cell_array{1} = 'seleziona variabile...';
            for i=2:length(vars)+1
                cell_array{i} = vars(i-1).name;
            end
            app.segnaleDropDown.Items = cell_array;
        end
        
        %         function loadWorkspace(app)
        %             uiopen('load') % carica un workspace
        %
        %             % trasferisci tutte le variabili dal ws locale al globale
        %             vars = evalin('caller', 'whos');
        %             for i=1:length(vars)
        %                 assignin('base', vars(i).name, evalin('caller', vars(i).name));
        %             end
        %
        %             loadDropDownContent(app)
        %         end
        
        function plotDerSecondaESoglie(app)
            hold(app.plotDeriv, 'on')
            cla(app.plotDeriv) % pulisce il grafico
            time = app.sens_data(1:end-2,1);
            plot(app.plotDeriv, time, app.der_seconda, '.') % derivata seconda
            plot(app.plotDeriv, [time(1,1) time(end,1)], [app.soglia app.soglia], 'k') % soglie
            plot(app.plotDeriv, [time(1,1) time(end,1)], -[app.soglia app.soglia], 'k')
            hold(app.plotDeriv, 'off')
            app.plotDeriv.XLim = [time(1,1) time(end,1)];
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             app.plotSensore.Visible = 'off';
            %             app.IdentificazionesuddivisioniPanel.Visible = 'off';
            app.GeneradivisionisuidatiButton.Enable = 'off';
            app.SogliaSlider.Enable = 'off';
            app.SogliaField.Enable = 'off';
            
            dropDownCaricaVariabili(app)
        end

        % Button pushed function: ricaricaButton
        function ricaricaButtonPushed(app, event)
            dropDownCaricaVariabili(app)
        end

        % Value changed function: segnaleDropDown
        function segnaleDropDownValueChanged(app, event)
            varName = app.segnaleDropDown.Value;
            
            if strcmp(varName, 'seleziona variabile...')
                cla(app.plotSensore) % pulisce il grafico
                
                app.GeneradivisionisuidatiButton.Enable = 'off';
                app.SogliaSlider.Enable = 'off';
                app.SogliaField.Enable = 'off';
                
            else % variabile selezionata
                % TODO: controlla che la variabile abbia il formato corretto
                
                app.GeneradivisionisuidatiButton.Enable = 'on';
                app.SogliaSlider.Enable = 'on';
                app.SogliaField.Enable = 'on';
                
                % la variabile deve essere una matrice n_samples x 2: [tempi, valori]
                app.sens_data = evalin('base', varName);
                
                % tempi riferiti al primo campione
                %                 app.sens_data(:,1) = app.sens_data(:,1) - app.sens_data(1,1);
                
                % plot dati
                plot(app.plotSensore, app.sens_data(:,1), app.sens_data(:,2), '.')
                app.plotSensore.XLim = [app.sens_data(1,1) app.sens_data(end,1)];
                
                % calcolo derivata seconda
                app.der_seconda = smooth_double_derivate(app.sens_data(:,1), app.sens_data(:,2));
                
                % calcolo soglia
                soglia_max = max(app.der_seconda);
                app.soglia = soglia_max / 2;
                app.SogliaSlider.Limits = [0 soglia_max];
                app.SogliaSlider.Value = app.soglia;
                app.SogliaField.Value = app.soglia;
                
                plotDerSecondaESoglie(app)
            end
        end

        % Button pushed function: GeneradivisionisuidatiButton
        function GeneradivisionisuidatiButtonPushed(app, event)
            bool_sopra_soglia = app.der_seconda > app.soglia | app.der_seconda < -app.soglia;
            deriv_sopra_soglia = app.der_seconda .* bool_sopra_soglia;
            
            [peak_max, max_i] = findpeaks(deriv_sopra_soglia);
            [peak_min, min_i] = findpeaks(-deriv_sopra_soglia);
            
            index_and_peaks = [max_i, min_i; peak_max, -peak_min]'; % prima colonna indici, seconda valori
            index_and_peaks = sortrows(index_and_peaks); % ordina guardando la prima colonna
            index_and_peaks(index_and_peaks(:,2)==0,:) = []; % elimina quando uguale a zero
            
            
            app.all_peaks_i = index_and_peaks(:,1);
            all_peaks = index_and_peaks(:,2);
            
            plotDerSecondaESoglie(app)
            hold(app.plotDeriv,'on')
            plot(app.plotDeriv, app.sens_data(app.all_peaks_i,1), all_peaks,'*')
            hold(app.plotDeriv,'off')
            
            cla(app.plotSensore)
            hold(app.plotSensore,'on')
            plot(app.plotSensore, app.sens_data(:,1), app.sens_data(:,2), '.')
            yd = [min(app.sens_data(:,2)) max(app.sens_data(:,2))] * 1.2;
            for i=1:length(app.all_peaks_i)
                index = app.all_peaks_i(i);
                plot(app.plotSensore, [app.sens_data(index,1) app.sens_data(index,1)], yd)
            end
            hold(app.plotSensore,'off')
            
            
             %calcola M,thetaLS,thetaN1 
             if app.all_peaks_i(1) ~= 1
                app.all_peaks_i = [0; app.all_peaks_i];
            end
            if app.all_peaks_i(end) ~= length(app.sens_data)
                app.all_peaks_i = [app.all_peaks_i; length(app.sens_data)];
            end
            
            time = app.sens_data(:,1);
            signal= app.sens_data(:,2);
            n_divisions = length(app.all_peaks_i) - 1;
    
            
           app.Mr = [];
            
            for i=1:n_divisions
                part_time = time(app.all_peaks_i(i)+1:app.all_peaks_i(i+1))-time(app.all_peaks_i(i)+1);
                %part_signal = signal(app.all_peaks_i(i)+1:app.all_peaks_i(i+1));
                M_block = [part_time, ones(length(part_time),1)];
                app.Mr = blkdiag(app.Mr, M_block);
            end
            
            app.thetaLS = pinv(app.Mr) * signal;
            %dichiaro y e M globali altrimenti non posso passarle alle funzioni norm1_function e entropy_function
            global y
            global M
            y=signal;
            M=app.Mr;
            thLS=app.thetaLS;
            app.thetaN1 = fminsearch(@norm1_function, thLS);
   
        end

        % Value changed function: SogliaSlider
        function SogliaSliderValueChanged(app, event)
            app.soglia = app.SogliaSlider.Value;
            
            plotDerSecondaESoglie(app)
            
            app.SogliaField.Value = app.soglia;
        end

        % Value changed function: SogliaField
        function SogliaFieldValueChanged(app, event)
            new_soglia = app.SogliaField.Value;
            
            if new_soglia < max(app.SogliaSlider.Limits)
                app.SogliaSlider.Value = new_soglia;
                SogliaSliderValueChanged(app, event)
            end
        end

        % Button pushed function: LeastSquareButton
        function LSButton(app, event)
           
            yLS = app.Mr * app.thetaLS;
            
            cla(app.plotFit)
            hold(app.plotFit,'on')
            plot(app.plotFit, app.sens_data(:,1), app.sens_data(:,2), '.')
            hold on
            plot(app.plotFit,app.sens_data(:,1),yLS,'-r')
            hold(app.plotFit,'off')
            
        end

        % Button pushed function: Norma1Button
        function N1Button(app, event)
           
            yN1 = app.Mr * app.thetaN1;
            cla(app.plotFit)
            hold(app.plotFit,'on')
            plot(app.plotFit, app.sens_data(:,1), app.sens_data(:,2), '.')
            hold on
            plot(app.plotFit,app.sens_data(:,1),yN1,'-k')
            hold(app.plotFit,'off')
            
            
        end

        % Button pushed function: LeastEntropyLikeButton
        function LELButton(app, event)
            
            theta_init_1 = app.thetaLS;
            % theta_init_1 = theta_init_1 / norm(theta_init_1);
            theta_init_2 = app.thetaN1;
            % theta_init_2 = theta_init_2 / norm(theta_init_2);
solver_options = struct('GradObj','on','GradConstr',false);
[thetaLEL_1, H_min_1, exitflag_1, output_1, grad_1] = fminlbfgs(@entropy_function, theta_init_1, solver_options);
[thetaLEL_2, H_min_2, exitflag_2, output_2, grad_2] = fminlbfgs(@entropy_function, theta_init_2, solver_options);
            thetaLEL = [thetaLEL_1, thetaLEL_2];
            H_min = [H_min_1, H_min_2];
            [minH, min_index] = min(H_min);
            yLEL = app.Mr * thetaLEL(:,min_index);
            cla(app.plotFit)
            hold(app.plotFit,'on')
            plot(app.plotFit, app.sens_data(:,1), app.sens_data(:,2), '.')
            hold on
            plot(app.plotFit,app.sens_data(:,1),yLEL,'-g')
            hold(app.plotFit,'off')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SuddivisionedatiUIFigure = uifigure;
            app.SuddivisionedatiUIFigure.Name = 'Suddivisione dati';
            app.DatidelsensorePanel = uipanel(app.SuddivisionedatiUIFigure);
            app.DatidelsensorePanel.Title = 'Dati del sensore';
            app.dalworkspaceDropDownLabel = uilabel(app.DatidelsensorePanel);
            app.dalworkspaceDropDownLabel.Text = 'dal workspace';
            app.segnaleDropDown = uidropdown(app.DatidelsensorePanel);
            app.segnaleDropDown.Items = {'seleziona variabile...', 'Option 2', 'Option 3', 'Option 4'};
            app.segnaleDropDown.ValueChangedFcn = createCallbackFcn(app, @segnaleDropDownValueChanged, true);
            app.segnaleDropDown.Value = 'seleziona variabile...';
            app.ricaricaButton = uibutton(app.DatidelsensorePanel, 'push');
            app.ricaricaButton.ButtonPushedFcn = createCallbackFcn(app, @ricaricaButtonPushed, true);
            app.ricaricaButton.Text = 'ricarica';
            app.lavariabiledeveessereunamatricen_samplesx2tempivaloriLabel = uilabel(app.DatidelsensorePanel);
            app.lavariabiledeveessereunamatricen_samplesx2tempivaloriLabel.Text = 'la variabile deve essere una matrice n_samples x 2: [tempi, valori]';
            app.plotSensore = uiaxes(app.SuddivisionedatiUIFigure);
            title(app.plotSensore, 'Suddivisione dei dati')
            xlabel(app.plotSensore, 'Tempo')
            ylabel(app.plotSensore, 'Ampiezza')
            app.IdentificazionesuddivisioniPanel = uipanel(app.SuddivisionedatiUIFigure);
            app.IdentificazionesuddivisioniPanel.Title = 'Identificazione suddivisioni';
            app.plotDeriv = uiaxes(app.IdentificazionesuddivisioniPanel);
            title(app.plotDeriv, 'Derivata seconda')
            xlabel(app.plotDeriv, 'Tempo')
            ylabel(app.plotDeriv, 'Ampiezza')
            app.SogliaSliderLabel = uilabel(app.IdentificazionesuddivisioniPanel);
            app.SogliaSliderLabel.Text = 'Soglia';
            app.SogliaSlider = uislider(app.IdentificazionesuddivisioniPanel);
            app.SogliaSlider.ValueChangedFcn = createCallbackFcn(app, @SogliaSliderValueChanged, true);
            app.GeneradivisionisuidatiButton = uibutton(app.IdentificazionesuddivisioniPanel, 'push');
            app.GeneradivisionisuidatiButton.ButtonPushedFcn = createCallbackFcn(app, @GeneradivisionisuidatiButtonPushed, true);
            app.GeneradivisionisuidatiButton.Text = 'Genera divisioni sui dati';
            app.SogliaField = uieditfield(app.IdentificazionesuddivisioniPanel, 'numeric');
            app.SogliaField.ValueChangedFcn = createCallbackFcn(app, @SogliaFieldValueChanged, true);
            app.plotFit = uiaxes(app.SuddivisionedatiUIFigure);
            title(app.plotFit, 'Fitting')
            xlabel(app.plotFit, 'Tempo')
            ylabel(app.plotFit, 'Segnale')
            app.LeastSquareButton = uibutton(app.SuddivisionedatiUIFigure, 'push');
            app.LeastSquareButton.ButtonPushedFcn = createCallbackFcn(app, @LSButton, true);
            app.LeastSquareButton.Text = 'LeastSquare';
            app.LeastEntropyLikeButton = uibutton(app.SuddivisionedatiUIFigure, 'push');
            app.LeastEntropyLikeButton.ButtonPushedFcn = createCallbackFcn(app, @LELButton, true);
            app.LeastEntropyLikeButton.Text = 'LeastEntropyLike';
            app.Norma1Button = uibutton(app.SuddivisionedatiUIFigure, 'push');
            app.Norma1Button.ButtonPushedFcn = createCallbackFcn(app, @N1Button, true);
            app.Norma1Button.Text = 'Norma 1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AppIndiveri

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SuddivisionedatiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SuddivisionedatiUIFigure)
        end
    end
end