classdef AppMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MetodirobustiadoutliersUIFigure  matlab.ui.Figure
        DatidelsensorePanel         matlab.ui.container.Panel
        dalworkspaceDropDownLabel   matlab.ui.control.Label
        segnaleDropDown             matlab.ui.control.DropDown
        ricaricaButton              matlab.ui.control.Button
        trattacomevaloriangolariinCheckBox  matlab.ui.control.CheckBox
        plotSensore                 matlab.ui.control.UIAxes
        suddividiButton             matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        LeastSquareTab              matlab.ui.container.Tab
        CalcolaLSButton             matlab.ui.control.Button
        plotFitLS                   matlab.ui.control.UIAxes
        SalvanelworkspaceButtonLS   matlab.ui.control.Button
        apriplotButtonLS            matlab.ui.control.Button
        Norma1Tab                   matlab.ui.container.Tab
        CalcolaN1Button             matlab.ui.control.Button
        plotFitN1                   matlab.ui.control.UIAxes
        SalvanelworkspaceButtonN1   matlab.ui.control.Button
        apriplotButtonN1            matlab.ui.control.Button
        IndiveriTab                 matlab.ui.container.Tab
        CalcolaLELButton            matlab.ui.control.Button
        plotFitLEL                  matlab.ui.control.UIAxes
        premereedattendereLabel     matlab.ui.control.Label
        SalvanelworkspaceButtonLEL  matlab.ui.control.Button
        apriplotButtonLEL           matlab.ui.control.Button
        HistogramLELvsLSTab         matlab.ui.container.Tab
        HistogramLELvsLS            matlab.ui.control.UIAxes
        apriplotButton              matlab.ui.control.Button
        SalvanelworkspaceButton     matlab.ui.control.Button
        HistogramN1vsLSTab          matlab.ui.container.Tab
        HistogramN1                 matlab.ui.control.UIAxes
        apriplotButton_2            matlab.ui.control.Button
        salvanelworkspaceButton     matlab.ui.control.Button
        lavariabiledeveessereunamatricen_samplesx2tempivaloriLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        sens_data         % segnale
        sens_data_divided % segnale diviso
        
        is_angles         % flag per trattare il segnale come angolare
        fcut              % frequenza di taglio del filtro usato in AppFilter
        
        Mr                % matrice di regressione
        thetaLS           % soluzione ai minimi quadrati
        thetaN1           % soluzione in norma uno
        
        yLS               % segnale stimato con norma LS
        yN1               % segnale stimato con norma N1
        yLEL              % segnale stimato con norma LEL
        
        rLS               % residuo con norma LS
        rN1               % residuo con norma N1
        rLEL              % residuo con norma LEL
        
    end
    
    methods (Access = private)
        
        % popola il menù a tendina dal workspace
        function dropDownCaricaVariabili(app)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars)+[1,0]);
            cell_array{1} = 'seleziona variabile...';
            for i=2:length(vars)+1
                cell_array{i} = vars(i-1).name;
            end
            app.segnaleDropDown.Items = cell_array;
        end
        
        % abilita/disabilita i pulsanti di calcolo
        function abilitaCalcoli(app, is_enable)
            if is_enable
                app.CalcolaLSButton.Enable = 'on';
                app.CalcolaN1Button.Enable = 'on';
                app.CalcolaLELButton.Enable = 'on';
            else
                app.CalcolaLSButton.Enable = 'off';
                app.CalcolaN1Button.Enable = 'off';
                app.CalcolaLELButton.Enable = 'off';
                
                app.SalvanelworkspaceButtonLS.Enable = 'off';
                app.apriplotButtonLS.Enable = 'off';
                app.SalvanelworkspaceButtonN1.Enable = 'off';
                app.apriplotButtonN1.Enable = 'off';
                app.SalvanelworkspaceButtonLEL.Enable = 'off';
                app.apriplotButtonLEL.Enable = 'off';
                app.apriplotButton.Enable = 'off';
                app.apriplotButton_2.Enable = 'off';
                
            end
        end
        
        % se il segnale è angolare occorre sottrargli 2π ogni volta la differenza tra ogni
        % valore ed il precedente risulta prossima a 2π (implementato come > 3/2 π)
        function dataset = correzioneAngolare(app, dataset)
            if app.is_angles
                for j=2:length(dataset(:,2))
                    if dataset(j,2) - dataset(j-1,2) > 3/2*pi
                        for k=j:length(dataset)
                            dataset(k,2) = dataset(k,2) - 2*pi;
                        end
                    else
                        if dataset(j,2) - dataset(j-1,2) < -3/2*pi
                            for k=j:length(app.signal)
                                dataset(k,2) = dataset(k,2) + 2*pi;
                            end
                        end
                    end
                end
            end
        end
        
        % riporta nel dominio [-pi, pi], se i dati sono angolari
        function valori = ritrasponiAngolare(app, valori)
            if app.is_angles
                for i=1:length(valori)
                    if valori(i) > pi
                        valori(i) = valori (i) - 2*pi;
                    else
                        if valori(i) < -pi
                            valori(i) = valori (i) + 2*pi;
                        end
                    end
                end
            end
        end
        
        % calcola la matrice di regressione, la soluzione ai minimi quadrati e quella in norma 1
        function regressione(app)
            app.Mr = [];
            
            % forma Mr come diagonale a blocchi, con una suddivisone per blocco
            for i=1:length(app.sens_data_divided)
                segment = correzioneAngolare(app, app.sens_data_divided{i}); % suddivisione i-esima del segnale
                
                M_block = [segment(:,1)-segment(1,1), ones(length(segment),1)];
                app.Mr = blkdiag(app.Mr, M_block);
            end
            
            dati_corretti = correzioneAngolare(app, app.sens_data);
            app.thetaLS = pinv(app.Mr) * dati_corretti(:,2); % soluzione ai minimi quadrati
            
            % dichiaro y e M globali altrimenti non posso passarle alle funzioni norm1_function e entropy_function
            % che vengono richiamate se viene richiesto il fitting in norma 1 o a entropia minima,rispettivamente
            global y
            global M
            y = dati_corretti(:,2);
            M = app.Mr;
            options=optimset('MaxFunEvals',10000,'Display','off');
            [app.thetaN1, ~, exitflag] = fminunc(@norm1_function, app.thetaLS, options);
            if exitflag == 0 % uscito per numerio di iterazioni massimo raggiunto, quindi eseguendo altre iterazioni si per migliorare
                [app.thetaN1, ~, ~] = fminunc(@norm1_function, app.thetaN1, options);
            end
        end
        
        function salvaInWorkspace(~, var, default_name)
            var_name = inputdlg('Nome della variabile di destinazione', 'Salva nel workspace', 1, {default_name});
            assignin('base', var_name{1}, var);
        end
        
    end
    
    methods (Access = public)
        
        function getReturnAppSuddivisione(app, sens_data_divided, fcut)
            % salva la frequenza di taglio utilizzata, consentendo di ripartire da quella se si riapre AppFilter
            app.fcut = fcut;
            
            app.sens_data_divided = sens_data_divided;
            
            % salva nel workspace
            %             asignin('base', 'dati_suddivisi', app.sens_data_divided);
            
            % plot del segnale suddiviso
            cla(app.plotSensore)
            hold(app.plotSensore,'on')
            for i=1:length(app.sens_data_divided)
                segment = app.sens_data_divided{i};
                plot(app.plotSensore, segment(:,1), segment(:,2),'.')
            end
            hold(app.plotSensore,'off')
            
            regressione(app)
            
            abilitaCalcoli(app, true)
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fcut = -1; % questo valore permette ad AppFilter di impostarla automaticamente (quando l'utente avrà scelto un valore sarà invece impostato quello)
            
            abilitaCalcoli(app, false)
            app.suddividiButton.Enable = 'off';
            app.is_angles = false;
            
            dropDownCaricaVariabili(app)
        end

        % Button pushed function: ricaricaButton
        function ricaricaButtonPushed(app, event)
            dropDownCaricaVariabili(app)
        end

        % Value changed function: segnaleDropDown
        function segnaleDropDownValueChanged(app, event)
            varName = app.segnaleDropDown.Value;
            
            % se nessuna variabile è selezionata
            if strcmp(varName, 'seleziona variabile...')
                cla(app.plotSensore) % pulisce il grafico
                
                abilitaCalcoli(app, false)
                app.suddividiButton.Enable = 'off';
                
            else % variabile selezionata
                % TODO: controlla che la variabile abbia il formato corretto
                
                app.fcut = -1; % resetta la frequenza di taglio per AppFilter
                
                app.suddividiButton.Enable = 'on';
                
                % la variabile deve essere una matrice n_samples x 2: [tempi, valori]
                app.sens_data = evalin('base', varName);
                
                % plot dati
                plot(app.plotSensore, app.sens_data(:,1), app.sens_data(:,2), '.')
                app.plotSensore.XLim = [app.sens_data(1,1) app.sens_data(end,1)];
            end
        end

        % Button pushed function: suddividiButton
        function suddividiButtonPushed(app, event)
            % avvia AppSuddivisioneAutomatica, passandogli l'attuale valore di taglio, se esistente
            if app.fcut ~= -1
                AppSuddivisioneAutomatica(app, app.sens_data, app.is_angles, app.fcut)
            else
                AppSuddivisioneAutomatica(app, app.sens_data, app.is_angles)
            end
        end

        % Button pushed function: CalcolaLSButton
        function CalcolaLSButtonPushed(app, event)
            app.yLS = ritrasponiAngolare(app, app.Mr * app.thetaLS);  % segnale di fitting che minimizza la norma 2 dell'errore
            cla(app.plotFitLS)
            hold(app.plotFitLS,'on')
            plot(app.plotFitLS, app.sens_data(:,1), app.sens_data(:,2), '.')  %plot del segnale
            hold(app.plotFitLS,'on')
            plot(app.plotFitLS,app.sens_data(:,1),app.yLS,'.r','LineWidth',3) %plot del fitting
            hold(app.plotFitLS,'off')
            
            app.rLS=app.sens_data(:,2)-app.yLS;
            
            app.SalvanelworkspaceButtonLS.Enable = 'on';
            app.apriplotButtonLS.Enable = 'on';
        end

        % Button pushed function: CalcolaN1Button
        function CalcolaN1ButtonPushed(app, event)
            app.yN1 = ritrasponiAngolare(app, app.Mr * app.thetaN1); % segnale di fitting che minimizza la norma 1 dell'errore
            cla(app.plotFitN1)
            hold(app.plotFitN1,'on')
            plot(app.plotFitN1, app.sens_data(:,1), app.sens_data(:,2), '.') % plot del segnale
            hold(app.plotFitN1,'on')
            plot(app.plotFitN1,app.sens_data(:,1),app.yN1,'.k','LineWidth',3)  % plot del fitting
            hold(app.plotFitN1,'off')
            
            app.rN1=app.sens_data(:,2)-app.yN1;
            histogram(app.HistogramN1,app.rLS,50)
            hold(app.HistogramN1,'on')
            histogram(app.HistogramN1,app.rN1,50)
            legend(app.HistogramN1,'residui LS','residui N1')
            
            app.SalvanelworkspaceButtonN1.Enable = 'on';
            app.apriplotButtonN1.Enable = 'on';           
            app.apriplotButton_2.Enable = 'on';
        end

        % Button pushed function: CalcolaLELButton
        function CalcolaLELButtonPushed(app, event)
            fprintf("Avvio calcolo norma LEL...\n")
            
            theta_init_1 = app.thetaLS;  % valore 1 di inizializzazione dell'algoritmo BFGS
            theta_init_2 = app.thetaN1;  % valore 2 di inizializzazione dell'algoritmo BFGS
            solver_options = struct('GradObj','on','GradConstr',false);
            fprintf("-> Calcolo tramite valore iniziale LS...\n")
            [thetaLEL_1, H_min_1, ~, ~, ~] = fminlbfgs(@entropy_function, theta_init_1, solver_options);  %soluzione 1 a minima entropia
            fprintf("-> Calcolo tramite valore iniziale N1...\n")
            [thetaLEL_2, H_min_2, ~, ~, ~] = fminlbfgs(@entropy_function, theta_init_2, solver_options);  %soluzione 2 a minima entropia
            fprintf("LEL completato!\n")
            
            thetaLEL = [thetaLEL_1, thetaLEL_2]; % coppia di soluzioni
            H_min = [H_min_1, H_min_2];
            [~, min_index] = min(H_min);  % indice della soluzione ad entropia minima
            
            app.yLEL = ritrasponiAngolare(app, app.Mr * thetaLEL(:,min_index)); % segnale di fitting che minimizza l'entropia
            cla(app.plotFitLEL)
            hold(app.plotFitLEL,'on')
            plot(app.plotFitLEL, app.sens_data(:,1), app.sens_data(:,2), '.') % plot del segnale
            hold(app.plotFitLEL,'on')
            plot(app.plotFitLEL,app.sens_data(:,1),app.yLEL,'.g','LineWidth',3) % plot del fitting
            hold(app.plotFitLEL,'off')
            
            app.rLEL=app.sens_data(:,2)-app.yLEL;
            histogram(app.HistogramLELvsLS,app.rLEL,50)
            hold(app.HistogramLELvsLS,'on')
            histogram(app.HistogramLELvsLS,app.rLS,50)
            legend(app.HistogramLELvsLS,'residui LS','residui LEL')
            
            app.SalvanelworkspaceButtonLEL.Enable = 'on';
            app.apriplotButtonLEL.Enable = 'on';
            app.apriplotButton.Enable = 'on';
        end

        % Button pushed function: SalvanelworkspaceButtonLEL
        function SalvanelworkspaceButtonLELPushed(app, event)
            salvaInWorkspace(app, app.yLEL, 'yLEL');
        end

        % Button pushed function: SalvanelworkspaceButtonN1
        function SalvanelworkspaceButtonN1Pushed(app, event)
            salvaInWorkspace(app, app.yN1, 'yN1');
        end

        % Button pushed function: SalvanelworkspaceButtonLS
        function SalvanelworkspaceButtonLSPushed(app, event)
            salvaInWorkspace(app, app.yLS, 'yLS');
            
        end

        % Button pushed function: apriplotButtonLS
        function apriplotButtonLSPushed(app, event)
            figure('Name','norma LS')
            hold on
            plot(app.sens_data(:,1),app.sens_data(:,2),'.')
            plot(app.sens_data(:,1), app.yLS,'.')
            hold off            
        end

        % Button pushed function: apriplotButtonN1
        function apriplotButtonN1Pushed(app, event)
            figure('Name','norma N1')
            hold on
            plot(app.sens_data(:,1),app.sens_data(:,2),'.')
            plot(app.sens_data(:,1), app.yN1,'.')
            hold off
        end

        % Button pushed function: apriplotButtonLEL
        function apriplotButtonLELPushed(app, event)
            figure('Name','norma LEL')
            hold on
            plot(app.sens_data(:,1),app.sens_data(:,2),'.')
            plot(app.sens_data(:,1), app.yLEL,'.')
            hold off
        end

        % Value changed function: trattacomevaloriangolariinCheckBox
        function trattacomevaloriangolariinCheckBoxValueChanged(app, event)
            app.is_angles = app.trattacomevaloriangolariinCheckBox.Value;
        end

        % Button pushed function: apriplotButton
        function apriHistogram(app, event)
            figure('Name','Istogramma dei residui LELvsLS')
            histogram(app.rLEL,100)
            hold on
            histogram(app.rLS,100)
            legend('residui LEL','residui LS')
        end

        % Button pushed function: SalvanelworkspaceButton
        function saveHistogram(app, event)
            salvaInWorkspace(app, app.rLS, 'rLS');
            salvaInWorkspace(app, app.rLEL, 'rLEL');
        end

        % Button pushed function: apriplotButton_2
        function apriHistogramN1(app, event)
            figure('Name','Istogramma dei residui N1vsLS')
            histogram(app.rN1,100)
            hold on
            histogram(app.rLS,100)            
            legend('residui N1','residui LS')
        end

        % Button pushed function: salvanelworkspaceButton
        function saveResiduesN1(app, event)
            salvaInWorkspace(app, app.rLS, 'rLS');
            salvaInWorkspace(app, app.rN1, 'rN1');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MetodirobustiadoutliersUIFigure = uifigure;
            app.MetodirobustiadoutliersUIFigure.Name = 'Metodi robusti ad outliers';
            app.DatidelsensorePanel = uipanel(app.MetodirobustiadoutliersUIFigure);
            app.DatidelsensorePanel.Title = 'Dati del sensore';
            app.dalworkspaceDropDownLabel = uilabel(app.DatidelsensorePanel);
            app.dalworkspaceDropDownLabel.Text = 'dal workspace';
            app.segnaleDropDown = uidropdown(app.DatidelsensorePanel);
            app.segnaleDropDown.Items = {'seleziona variabile...', 'Option 2', 'Option 3', 'Option 4'};
            app.segnaleDropDown.ValueChangedFcn = createCallbackFcn(app, @segnaleDropDownValueChanged, true);
            app.segnaleDropDown.Value = 'seleziona variabile...';
            app.ricaricaButton = uibutton(app.DatidelsensorePanel, 'push');
            app.ricaricaButton.ButtonPushedFcn = createCallbackFcn(app, @ricaricaButtonPushed, true);
            app.ricaricaButton.Text = 'ricarica';
            app.trattacomevaloriangolariinCheckBox = uicheckbox(app.DatidelsensorePanel);
            app.trattacomevaloriangolariinCheckBox.ValueChangedFcn = createCallbackFcn(app, @trattacomevaloriangolariinCheckBoxValueChanged, true);
            app.trattacomevaloriangolariinCheckBox.Text = 'tratta come valori angolari in [-π, π]';
            app.plotSensore = uiaxes(app.MetodirobustiadoutliersUIFigure);
            title(app.plotSensore, 'Segnale e suddivisioni')
            xlabel(app.plotSensore, 'Tempo')
            ylabel(app.plotSensore, 'Ampiezza')
            app.suddividiButton = uibutton(app.MetodirobustiadoutliersUIFigure, 'push');
            app.suddividiButton.ButtonPushedFcn = createCallbackFcn(app, @suddividiButtonPushed, true);
            app.suddividiButton.Text = 'suddividi';
            app.TabGroup = uitabgroup(app.MetodirobustiadoutliersUIFigure);
            app.LeastSquareTab = uitab(app.TabGroup);
            app.LeastSquareTab.Title = 'Least Square';
            app.CalcolaLSButton = uibutton(app.LeastSquareTab, 'push');
            app.CalcolaLSButton.ButtonPushedFcn = createCallbackFcn(app, @CalcolaLSButtonPushed, true);
            app.CalcolaLSButton.Text = 'Calcola LS';
            app.plotFitLS = uiaxes(app.LeastSquareTab);
            title(app.plotFitLS, 'Fitting')
            xlabel(app.plotFitLS, 'Tempo')
            ylabel(app.plotFitLS, 'Segnale')
            app.SalvanelworkspaceButtonLS = uibutton(app.LeastSquareTab, 'push');
            app.SalvanelworkspaceButtonLS.ButtonPushedFcn = createCallbackFcn(app, @SalvanelworkspaceButtonLSPushed, true);
            app.SalvanelworkspaceButtonLS.Text = 'Salva nel workspace';
            app.apriplotButtonLS = uibutton(app.LeastSquareTab, 'push');
            app.apriplotButtonLS.ButtonPushedFcn = createCallbackFcn(app, @apriplotButtonLSPushed, true);
            app.apriplotButtonLS.Text = 'apri plot';
            app.Norma1Tab = uitab(app.TabGroup);
            app.Norma1Tab.Title = 'Norma 1';
            app.CalcolaN1Button = uibutton(app.Norma1Tab, 'push');
            app.CalcolaN1Button.ButtonPushedFcn = createCallbackFcn(app, @CalcolaN1ButtonPushed, true);
            app.CalcolaN1Button.Text = 'Calcola N1';
            app.plotFitN1 = uiaxes(app.Norma1Tab);
            title(app.plotFitN1, 'Fitting')
            xlabel(app.plotFitN1, 'Tempo')
            ylabel(app.plotFitN1, 'Segnale')
            app.SalvanelworkspaceButtonN1 = uibutton(app.Norma1Tab, 'push');
            app.SalvanelworkspaceButtonN1.ButtonPushedFcn = createCallbackFcn(app, @SalvanelworkspaceButtonN1Pushed, true);
            app.SalvanelworkspaceButtonN1.Text = 'Salva nel workspace';
            app.apriplotButtonN1 = uibutton(app.Norma1Tab, 'push');
            app.apriplotButtonN1.ButtonPushedFcn = createCallbackFcn(app, @apriplotButtonN1Pushed, true);
            app.apriplotButtonN1.Text = 'apri plot';
            app.IndiveriTab = uitab(app.TabGroup);
            app.IndiveriTab.Title = 'Indiveri';
            app.CalcolaLELButton = uibutton(app.IndiveriTab, 'push');
            app.CalcolaLELButton.ButtonPushedFcn = createCallbackFcn(app, @CalcolaLELButtonPushed, true);
            app.CalcolaLELButton.Text = 'Calcola LEL';
            app.plotFitLEL = uiaxes(app.IndiveriTab);
            title(app.plotFitLEL, 'Fitting')
            xlabel(app.plotFitLEL, 'Tempo')
            ylabel(app.plotFitLEL, 'Segnale')
            app.premereedattendereLabel = uilabel(app.IndiveriTab);
            app.premereedattendereLabel.Text = 'premere ed attendere, progressi visualizzati nella console di Matlab';
            app.SalvanelworkspaceButtonLEL = uibutton(app.IndiveriTab, 'push');
            app.SalvanelworkspaceButtonLEL.ButtonPushedFcn = createCallbackFcn(app, @SalvanelworkspaceButtonLELPushed, true);
            app.SalvanelworkspaceButtonLEL.Text = 'Salva nel workspace';
            app.apriplotButtonLEL = uibutton(app.IndiveriTab, 'push');
            app.apriplotButtonLEL.ButtonPushedFcn = createCallbackFcn(app, @apriplotButtonLELPushed, true);
            app.apriplotButtonLEL.Text = 'apri plot';
            app.HistogramLELvsLSTab = uitab(app.TabGroup);
            app.HistogramLELvsLSTab.Title = 'Histogram LELvsLS';
            app.HistogramLELvsLS = uiaxes(app.HistogramLELvsLSTab);
            title(app.HistogramLELvsLS, 'Title')
            xlabel(app.HistogramLELvsLS, 'X')
            ylabel(app.HistogramLELvsLS, 'Y')
            app.apriplotButton = uibutton(app.HistogramLELvsLSTab, 'push');
            app.apriplotButton.ButtonPushedFcn = createCallbackFcn(app, @apriHistogram, true);
            app.apriplotButton.Text = 'apri plot';
            app.SalvanelworkspaceButton = uibutton(app.HistogramLELvsLSTab, 'push');
            app.SalvanelworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @saveHistogram, true);
            app.SalvanelworkspaceButton.Text = 'Salva nel workspace';
            app.HistogramN1vsLSTab = uitab(app.TabGroup);
            app.HistogramN1vsLSTab.Title = 'Histogram N1vsLS';
            app.HistogramN1 = uiaxes(app.HistogramN1vsLSTab);
            title(app.HistogramN1, 'Title')
            xlabel(app.HistogramN1, 'X')
            ylabel(app.HistogramN1, 'Y')
            app.apriplotButton_2 = uibutton(app.HistogramN1vsLSTab, 'push');
            app.apriplotButton_2.ButtonPushedFcn = createCallbackFcn(app, @apriHistogramN1, true);
            app.apriplotButton_2.Text = 'apri plot';
            app.salvanelworkspaceButton = uibutton(app.HistogramN1vsLSTab, 'push');
            app.salvanelworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @saveResiduesN1, true);
            app.salvanelworkspaceButton.Text = 'salva nel workspace';
            app.lavariabiledeveessereunamatricen_samplesx2tempivaloriLabel = uilabel(app.MetodirobustiadoutliersUIFigure);
            app.lavariabiledeveessereunamatricen_samplesx2tempivaloriLabel.Text = 'la variabile deve essere una matrice n_samples x 2: [tempi, valori]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AppMain

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MetodirobustiadoutliersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MetodirobustiadoutliersUIFigure)
        end
    end
end