classdef AppSuddivisioneAutomatica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SuddivisionedatiUIFigure        matlab.ui.Figure
        plotSensore                     matlab.ui.control.UIAxes
        IdentificazionesuddivisioniPanel  matlab.ui.container.Panel
        plotDeriv                       matlab.ui.control.UIAxes
        SogliaSliderLabel               matlab.ui.control.Label
        SogliaSlider                    matlab.ui.control.Slider
        SogliaField                     matlab.ui.control.NumericEditField
        SuddivisionedatiLabel           matlab.ui.control.Label
        AggiornadivisionisuidatiButton  matlab.ui.control.Button
        ConfermaedesciButton            matlab.ui.control.Button
        FiltrailsegnaleButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        calling_app       % oggetto della finestra chiamante
        
        sens_data         % segnale
        sens_data_divided % matrice di celle contententi sens_data suddiviso
        
        filtered_signal   % segnale filtrato da AppFilter
        fcut              % frequenza di taglio del filtro usato in AppFilter
        
        is_angles         % flag per trattare il segnale come angolare
        der_seconda       % derivata seconda del segnale filtrato
        
        soglia            % valore assoluto della derivata seconda oltre il quale valutare i picchi
        index_and_peaks   % matrice n x 2 con [indici, valori] dei punti di taglio identificati (picchi nella derivata seconda)
    end
    
    methods (Access = private)
        
        % abilita/disabilita gli input
        function abilitaInputs(app, is_enable)
            if is_enable
                %             app.plotSensore.Visible = 'on';
                %             app.IdentificazionesuddivisioniPanel.Visible = 'on';
                app.AggiornadivisionisuidatiButton.Enable = 'on';
                app.SogliaSlider.Enable = 'on';
                app.SogliaField.Enable = 'on';
%                 app.FiltrailsegnaleButton.Enable = 'on';
            else
                %             app.plotSensore.Visible = 'off';
                %             app.IdentificazionesuddivisioniPanel.Visible = 'off';
                app.AggiornadivisionisuidatiButton.Enable = 'off';
                app.SogliaSlider.Enable = 'off';
                app.SogliaField.Enable = 'off';
%                 app.FiltrailsegnaleButton.Enable = 'off';
            end
        end
        
        % grafico di destra
        function plotDerSecondaESoglie(app)
            hold(app.plotDeriv, 'on')
            cla(app.plotDeriv) % pulisce il grafico
            time = app.sens_data(1:length(app.der_seconda),1);
            plot(app.plotDeriv, time, app.der_seconda, '.') % derivata seconda
            plot(app.plotDeriv, [time(1,1) time(end,1)], [app.soglia app.soglia], 'k') % soglie
            plot(app.plotDeriv, [time(1,1) time(end,1)], -[app.soglia app.soglia], 'k')
            hold(app.plotDeriv, 'off')
            app.plotDeriv.XLim = [time(1,1) time(end,1)];
        end
        
        function generaDivisioni(app)
            division_points = app.index_and_peaks(:,1); % indici delle divisioni
            
            % aggiungi primo e ultimo indice ai punti di suddivisione
            if division_points(1) ~= 1
                division_points = [0; division_points];
            end
            if division_points(end) ~= length(app.sens_data)
                division_points = [division_points; length(app.sens_data)];
            end
            
            time = app.sens_data(:,1);
                        
            n_divisions = length(division_points) - 1;
            app.sens_data_divided = cell(n_divisions, 1);
            for i=1:n_divisions
                part_time = time(division_points(i)+1:division_points(i+1));
                part_sens_data = app.sens_data(division_points(i)+1:division_points(i+1),2);
                app.sens_data_divided{i} = [part_time part_sens_data];
            end
            
            %             figure
            %             hold on
            %             for i=1:n_divisions
            %                 segment = app.sens_data_divided{i};
            %                 plot(segment(:,1),segment(:,2),'.')
            %             end
        end
        
        function calcolaDerSeconda(app)
            % se il segnale è angolare occorre sottrarre 2π ad ogni differenza che risulta prossima a 2π (implementato come > 3/2 π)
            der_prima = diff(app.filtered_signal);
            if app.is_angles
                for i=1:length(der_prima)
                    if der_prima(i) > 3/2*pi
                        der_prima(i) = der_prima(i) - 2*pi;
                    else
                        if der_prima(i) < -3/2*pi
                            der_prima(i) = der_prima(i) + 2*pi;
                        end
                    end
                end
            end
            app.der_seconda = diff(der_prima)';
        end
    end
    
    methods (Access = public)
        
        % recupera il segnale filtrato dall'apposita app e calcola derivata seconda e soglie
        function getReturnAppFilter(app, filtered_signal, fcut)
            % salva la frequenza di taglio utilizzata, consentendo di ripartire da quella se si riapre AppFilter
            app.fcut = fcut;
            app.filtered_signal = filtered_signal;
            
            % calcolo derivata seconda
            calcolaDerSeconda(app)
            
            % calcolo soglia
            soglia_max = max(app.der_seconda);
            app.soglia = soglia_max / 2;
            app.SogliaSlider.Limits = [0 soglia_max];
            app.SogliaSlider.Value = app.soglia;
            app.SogliaField.Value = app.soglia;
            
            plotDerSecondaESoglie(app)
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app, signal, is_angles, fcut)
            if nargin == 1 % per debug
                app.calling_app = 0;
                app.sens_data = evalin('base', 'sens_B_orig');
                app.is_angles = true;
            else
                app.calling_app = calling_app;
                % la variabile deve essere una matrice n_samples x 2: [tempi, valori]
                app.sens_data = signal;
                app.is_angles = is_angles;
            end
            
            % se viene passata anche una frequenza di taglio per AppFilter imposta quella
            if nargin == 5
                app.fcut = fcut;
            else
                app.fcut = -1;
            end
            
            app.ConfermaedesciButton.Enable = 'off';
            
            abilitaInputs(app, false)
            
            % plot dati
            plot(app.plotSensore, app.sens_data(:,1), app.sens_data(:,2), '.')
            app.plotSensore.XLim = [app.sens_data(1,1) app.sens_data(end,1)];
            
            % filtra il segnale tramite l'app apposita
%             AppFilter(app, app.sens_data(:,2));
            % si aspetta quindi che l'app richiami la funzione getReturnAppFilter
        end

        % Button pushed function: AggiornadivisionisuidatiButton
        function AggiornadivisionisuidatiButtonPushed(app, event)
            % identifica i campioni in valore assoluto superiori alla soglia posta
            bool_sopra_soglia = abs(app.der_seconda) > app.soglia;
            
            % riscrive la derivata seconda con le sole parti sopra soglia
            deriv_sopra_soglia = app.der_seconda .* bool_sopra_soglia;
            
            [peak_max, max_i] = findpeaks(deriv_sopra_soglia);
            [peak_min, min_i] = findpeaks(-deriv_sopra_soglia);
            
            app.index_and_peaks = [max_i, min_i; peak_max, -peak_min]'; % prima riga indici, seconda valori
            app.index_and_peaks = sortrows(app.index_and_peaks); % ordina guardando la prima colonna
            app.index_and_peaks(app.index_and_peaks(:,2)==0,:) = []; % elimina quando uguale a zero
            
            all_peaks_i = app.index_and_peaks(:,1);
            all_peaks = app.index_and_peaks(:,2);
            
            % aggiunta di asterischi nei picchi identificati della derivata seconda
            plotDerSecondaESoglie(app)
            hold(app.plotDeriv,'on')
            plot(app.plotDeriv, app.sens_data(all_peaks_i,1), all_peaks,'*')
            hold(app.plotDeriv,'off')
            
            % plot dati e segioni di separazione
            cla(app.plotSensore)
            hold(app.plotSensore,'on')
            plot(app.plotSensore, app.sens_data(:,1), app.sens_data(:,2), '.') % dati grezzi
            
            y = [min(app.sens_data(:,2)) max(app.sens_data(:,2))] * 1.2; % ampiezza delle righe verticali
            for i=1:length(all_peaks_i)
                index = all_peaks_i(i);
                plot(app.plotSensore, [app.sens_data(index,1) app.sens_data(index,1)], y) % separatori
            end
            hold(app.plotSensore,'off')
            
            app.ConfermaedesciButton.Enable = 'on';
        end

        % Value changed function: SogliaSlider
        function SogliaSliderValueChanged(app, event)
            app.soglia = app.SogliaSlider.Value;
            app.SogliaField.Value = app.soglia;
            
            plotDerSecondaESoglie(app)
        end

        % Value changed function: SogliaField
        function SogliaFieldValueChanged(app, event)
            new_soglia = app.SogliaField.Value;
            
            if new_soglia < max(app.SogliaSlider.Limits)
                app.SogliaSlider.Value = new_soglia;
                SogliaSliderValueChanged(app, event)
            end
        end

        % Button pushed function: FiltrailsegnaleButton
        function FiltrailsegnaleButtonPushed(app, event)
            % avvia AppFilter, passandogli l'attuale valore di taglio, se esistente
            if app.fcut ~= -1
                AppFilter(app, app.sens_data(:,2), app.is_angles, app.fcut);
            else
                AppFilter(app, app.sens_data(:,2), app.is_angles);
            end
            
            abilitaInputs(app,true)
        end

        % Button pushed function: ConfermaedesciButton
        function ConfermaedesciButtonPushed(app, event)
            generaDivisioni(app)
            
            % restituisce il segnale tagliato alla finestra chiamante (AppMain)
            if app.calling_app ~= 0
                getReturnAppSuddivisione(app.calling_app, app.sens_data_divided, app.fcut)
            end
            
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SuddivisionedatiUIFigure = uifigure;
            app.SuddivisionedatiUIFigure.Name = 'Suddivisione dati';
            app.plotSensore = uiaxes(app.SuddivisionedatiUIFigure);
            title(app.plotSensore, 'Segnale e suddivisioni')
            xlabel(app.plotSensore, 'Tempo')
            ylabel(app.plotSensore, 'Ampiezza')
            app.IdentificazionesuddivisioniPanel = uipanel(app.SuddivisionedatiUIFigure);
            app.IdentificazionesuddivisioniPanel.Title = 'Identificazione suddivisioni';
            app.plotDeriv = uiaxes(app.IdentificazionesuddivisioniPanel);
            title(app.plotDeriv, 'Derivata seconda')
            xlabel(app.plotDeriv, 'Tempo')
            ylabel(app.plotDeriv, 'Ampiezza')
            app.SogliaSliderLabel = uilabel(app.IdentificazionesuddivisioniPanel);
            app.SogliaSliderLabel.Text = 'Soglia';
            app.SogliaSlider = uislider(app.IdentificazionesuddivisioniPanel);
            app.SogliaSlider.ValueChangedFcn = createCallbackFcn(app, @SogliaSliderValueChanged, true);
            app.SogliaField = uieditfield(app.IdentificazionesuddivisioniPanel, 'numeric');
            app.SogliaField.ValueChangedFcn = createCallbackFcn(app, @SogliaFieldValueChanged, true);
            app.SuddivisionedatiLabel = uilabel(app.SuddivisionedatiUIFigure);
            app.SuddivisionedatiLabel.Text = 'Suddivisione dati';
            app.AggiornadivisionisuidatiButton = uibutton(app.SuddivisionedatiUIFigure, 'push');
            app.AggiornadivisionisuidatiButton.ButtonPushedFcn = createCallbackFcn(app, @AggiornadivisionisuidatiButtonPushed, true);
            app.AggiornadivisionisuidatiButton.Text = 'Aggiorna divisioni sui dati';
            app.ConfermaedesciButton = uibutton(app.SuddivisionedatiUIFigure, 'push');
            app.ConfermaedesciButton.ButtonPushedFcn = createCallbackFcn(app, @ConfermaedesciButtonPushed, true);
            app.ConfermaedesciButton.Text = 'Conferma ed esci';
            app.FiltrailsegnaleButton = uibutton(app.SuddivisionedatiUIFigure, 'push');
            app.FiltrailsegnaleButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrailsegnaleButtonPushed, true);
            app.FiltrailsegnaleButton.Text = 'Filtra il segnale';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AppSuddivisioneAutomatica(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SuddivisionedatiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SuddivisionedatiUIFigure)
        end
    end
end