classdef QRODApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        QuantileRegressionTab           matlab.ui.container.Tab
        Tab1Axes                        matlab.ui.control.UIAxes
        apriplotButtonT1                matlab.ui.control.Button
        DetectedOutliersTab             matlab.ui.container.Tab
        Tab2Axes                        matlab.ui.control.UIAxes
        apriplotButtonT2                matlab.ui.control.Button
        LowerQuantileEditFieldLabel     matlab.ui.control.Label
        LowerQuantileEditField          matlab.ui.control.NumericEditField
        UpperQuantileEditFieldLabel     matlab.ui.control.Label
        UpperQuantileEditField          matlab.ui.control.NumericEditField
        ComputeButton                   matlab.ui.control.Button
        SaveandExitButton               matlab.ui.control.Button
        SelectedInputDropDownLabel      matlab.ui.control.Label
        SelectedInputDropDown           matlab.ui.control.DropDown
        PredictionspacedivisionsEditFieldLabel  matlab.ui.control.Label
        PredictionspacedivisionsEditField  matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.NumericEditField
        nofInterquantileintervalsLabel  matlab.ui.control.Label
        spaceDivisionSlider             matlab.ui.control.Slider
        lowerQuantileSlider             matlab.ui.control.Slider
        upperQuantileSlider             matlab.ui.control.Slider
        tolleranceSlider                matlab.ui.control.Slider
    end


    properties (Access = private)
        signal      % Input signal
        t           % Equivalent time
        
        tau         % Quantile range vector [lowerQ upperQ]
        psd         % Prediction space divisions
        k           % Tolerance
        lqf         % Lower quantile fence
        uqf         % Upper quantile fence
        
        purged      % Purged signal
        linInterp   % Purged signal linear interpolation
        
        predT       % Prediction space
        predMean    % Predicted Mean
        predMedian  % Predicted Median
        
        outliers    % Outliers column vector [position, value]
    end

    methods (Access = private)
        
        % Disables User commands/edit fields
        function disableFieldsAndButtons(app)
            app.ComputeButton.Enable = 'off';
            app.SaveandExitButton.Enable = 'off';
            app.LowerQuantileEditField.Enable = 'off';
            app.UpperQuantileEditField.Enable = 'off';
            app.ToleranceEditField.Enable = 'off';
            app.PredictionspacedivisionsEditField.Enable = 'off';
            app.spaceDivisionSlider.Enable = 'off';
            app.lowerQuantileSlider.Enable = 'off';
            app.upperQuantileSlider.Enable = 'off';
            app.tolleranceSlider.Enable = 'off';
        end
        
        % Enables User commands/edit fields
        function enableFieldsAndButtons(app)
            app.ComputeButton.Enable = 'on';
            app.SaveandExitButton.Enable = 'on';
            app.LowerQuantileEditField.Enable = 'on';
            app.UpperQuantileEditField.Enable = 'on';
            app.ToleranceEditField.Enable = 'on';
            app.PredictionspacedivisionsEditField.Enable = 'on';
            app.spaceDivisionSlider.Enable = 'on';
            app.lowerQuantileSlider.Enable = 'on';
            app.upperQuantileSlider.Enable = 'on';
            app.tolleranceSlider.Enable = 'on';
        end
        
        % Loads Input signal by dropdown menu
        function loadInput(app)
            vars = evalin('base', 'whos');              % Loads from current workspace
            cell_array = cell(size(vars)+[1,0]);        % Dropdown menu size
            cell_array{1} = 'seleziona variabile...';   % Sets default dropdown message
            
            % Loads data from workspace to cell array
            for i=2:length(vars)+1
                cell_array{i} = vars(i-1).name;
            end
            
            app.SelectedInputDropDown.Items = cell_array;     % Loads data from workspace to cell array
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Disable buttons and edi fields
            disableFieldsAndButtons(app);
            % Execute Data loading in dropdown menu
            loadInput(app);
        end

        % Value changed function: SelectedInputDropDown
        function SelectedInputDropDownValueChanged(app, event)
            varName = app.SelectedInputDropDown.Value;
            
            if strcmp(varName, 'seleziona variabile...') % No signal selected
                
                cla(app.Tab1Axes)               % Clears Tab1 axes
                disableFieldsAndButtons(app);   % Disable user commands/edit fields
            
            else % A signal is selected
                
                enableFieldsAndButtons(app);          % Enables user commands/edit fields
                
                loaded_signal = evalin('base', varName); % Loads signal
                
                % Set signal and equivalent time
                if min(size(loaded_signal)) == 1
                    app.signal = loaded_signal;
                    app.t = (0:length(app.signal)-1)';
                else
                    app.signal = loaded_signal(:,2);
                    app.t = loaded_signal(:,1);
                end
                
                % Display selected signal in Tab1
                cla(app.Tab1Axes) % Clears Tab1 axes
                plot(app.Tab1Axes, app.t, app.signal,'.') % Plots signal in Tab1 axes
                
                ComputeButtonPushed(app, event)
            end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            app.tau = [app.LowerQuantileEditField.Value, 
                app.UpperQuantileEditField.Value];
            
            app.psd = app.PredictionspacedivisionsEditField.Value;
            
            app.k = app.ToleranceEditField.Value;
            
            [app.outliers,app.purged,app.linInterp,app.predT,app.predMean,app.predMedian,app.lqf,app.uqf] = qrod(app.signal, app.tau, app.psd, app.k);
            
            % Display Tab1
            cla(app.Tab1Axes)               % Clears Tab1 axes
            hold(app.Tab1Axes, 'on');
            plot(app.Tab1Axes, app.signal, '.')                     % Plots signal
            plot(app.Tab1Axes, app.predMean, 'LineWidth',2)         % Plots predicted mean
            plot(app.Tab1Axes, app.predMedian, 'LineWidth',2)       % Plots predicted median
            plot(app.Tab1Axes, app.lqf, 'LineWidth',2)              % Plots lower quantile fence
            plot(app.Tab1Axes, app.uqf, 'LineWidth',2)              % Plots upper quantile fence
            hold(app.Tab1Axes, 'off');
            legend(app.Tab1Axes, 'input signal    ','predicted mean  ','predicted median','lower quantile  ','upper quantile  ');
            title(app.Tab1Axes, 'Quantile regression')
            
            % Display Tab2
            cla(app.Tab2Axes)               % Clears Tab2 axes
            hold(app.Tab2Axes, 'on');
            plot(app.Tab2Axes, app.linInterp, 'LineWidth',2)                % Plots purged signal linear interpolation
            plot(app.Tab2Axes, app.outliers(:,1), app.outliers(:,2), '*')   % Plots detected outliers
            hold(app.Tab2Axes, 'off');
            legend(app.Tab2Axes, 'purged signal linear interpolation','detected outliers                 ');
            title(app.Tab2Axes, 'Detected outliers');
        end

        % Button pushed function: SaveandExitButton
        function SaveandExitButtonPushed(app, event)
            % Exports detected outliers and linear-interpolated signal in workspace
            var_names = inputdlg({'Dati ripuliti:','Outliers:'}, 'Salva nel workspace', 1, {'dati_ripuliti','outliers'});
            assignin('base', var_names{1}, app.linInterp);
            assignin('base', var_names{2}, app.outliers);
            
            % Close app
            app.delete;
        end

        % Value changed function: spaceDivisionSlider
        function spaceDivisionSliderValueChanged(app, event)
            % update textfield and compute
            app.PredictionspacedivisionsEditField.Value = round(app.spaceDivisionSlider.Value);
            ComputeButtonPushed(app, event)
        end

        % Value changed function: PredictionspacedivisionsEditField
        function PredictionspacedivisionsEditFieldValueChanged(app, event)
            % update slider and compute
            app.spaceDivisionSlider.Value = app.PredictionspacedivisionsEditField.Value;
            ComputeButtonPushed(app, event)
        end

        % Value changing function: spaceDivisionSlider
        function spaceDivisionSliderValueChanging(app, event)
            app.PredictionspacedivisionsEditField.Value = round(event.Value); % only update texfield
        end

        % Value changed function: lowerQuantileSlider
        function lowerQuantileSliderValueChanged(app, event)
            app.LowerQuantileEditField.Value = app.lowerQuantileSlider.Value;
            ComputeButtonPushed(app, event)
        end

        % Value changed function: LowerQuantileEditField
        function LowerQuantileEditFieldValueChanged(app, event)
            app.lowerQuantileSlider.Value = app.LowerQuantileEditField.Value;
            ComputeButtonPushed(app, event)
        end

        % Value changing function: lowerQuantileSlider
        function lowerQuantileSliderValueChanging(app, event)
            app.LowerQuantileEditField.Value = event.Value;
        end

        % Value changed function: upperQuantileSlider
        function upperQuantileSliderValueChanged(app, event)
            app.UpperQuantileEditField.Value = app.upperQuantileSlider.Value;
            ComputeButtonPushed(app, event)
        end

        % Value changed function: UpperQuantileEditField
        function UpperQuantileEditFieldValueChanged(app, event)
            app.upperQuantileSlider.Valu = app.UpperQuantileEditField.Value;
            ComputeButtonPushed(app, event)
        end

        % Value changing function: upperQuantileSlider
        function upperQuantileSliderValueChanging(app, event)
            app.UpperQuantileEditField.Value = event.Value;
        end

        % Value changed function: tolleranceSlider
        function tolleranceSliderValueChanged(app, event)
            app.ToleranceEditField.Value = round(app.tolleranceSlider.Value);
            ComputeButtonPushed(app, event)
        end

        % Value changed function: ToleranceEditField
        function ToleranceEditFieldValueChanged(app, event)
            app.tolleranceSlider.Value = app.ToleranceEditField.Value;
            ComputeButtonPushed(app, event)
        end

        % Value changing function: tolleranceSlider
        function tolleranceSliderValueChanging(app, event)
            app.ToleranceEditField.Value = round(event.Value);
        end

        % Button pushed function: apriplotButtonT1
        function apriplotButtonT1Pushed(app, event)
            figure('Name','Quantile Regression plot')
            hold 'on';
            plot(app.signal, '.')                     % Plots signal
            plot(app.predMean, 'LineWidth',2)         % Plots predicted mean
            plot(app.predMedian, 'LineWidth',2)       % Plots predicted median
            plot(app.lqf, 'LineWidth',2)              % Plots lower quantile fence
            plot(app.uqf, 'LineWidth',2)              % Plots upper quantile fence
            hold('off');
            legend('input signal    ','predicted mean  ','predicted median','lower quantile  ','upper quantile  ');
        end

        % Button pushed function: apriplotButtonT2
        function apriplotButtonT2Pushed(app, event)
            figure('Name','Detected Outliers plot')
            hold('on');
            plot(app.linInterp, 'LineWidth',2)                % Plots purged signal linear interpolation
            plot(app.outliers(:,1), app.outliers(:,2), '*')   % Plots detected outliers
            hold('off');
            legend('purged signal linear interpolation','detected outliers');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.QuantileRegressionTab = uitab(app.TabGroup);
            app.QuantileRegressionTab.Title = 'Quantile Regression';
            app.Tab1Axes = uiaxes(app.QuantileRegressionTab);
            title(app.Tab1Axes, 'Title')
            xlabel(app.Tab1Axes, 'X')
            ylabel(app.Tab1Axes, 'Y')
            app.apriplotButtonT1 = uibutton(app.QuantileRegressionTab, 'push');
            app.apriplotButtonT1.ButtonPushedFcn = createCallbackFcn(app, @apriplotButtonT1Pushed, true);
            app.apriplotButtonT1.Text = 'open plot';
            app.DetectedOutliersTab = uitab(app.TabGroup);
            app.DetectedOutliersTab.Title = 'Detected Outliers';
            app.Tab2Axes = uiaxes(app.DetectedOutliersTab);
            title(app.Tab2Axes, 'Title')
            xlabel(app.Tab2Axes, 'X')
            ylabel(app.Tab2Axes, 'Y')
            app.apriplotButtonT2 = uibutton(app.DetectedOutliersTab, 'push');
            app.apriplotButtonT2.ButtonPushedFcn = createCallbackFcn(app, @apriplotButtonT2Pushed, true);
            app.apriplotButtonT2.Text = 'open plot';
            app.LowerQuantileEditFieldLabel = uilabel(app.UIFigure);
            app.LowerQuantileEditFieldLabel.Text = 'Lower Quantile';
            app.LowerQuantileEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerQuantileEditField.Limits = [0 0.5];
            app.LowerQuantileEditField.ValueChangedFcn = createCallbackFcn(app, @LowerQuantileEditFieldValueChanged, true);
            app.LowerQuantileEditField.Value = 0.2;
            app.UpperQuantileEditFieldLabel = uilabel(app.UIFigure);
            app.UpperQuantileEditFieldLabel.Text = 'Upper Quantile';
            app.UpperQuantileEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperQuantileEditField.Limits = [0.5 1];
            app.UpperQuantileEditField.ValueChangedFcn = createCallbackFcn(app, @UpperQuantileEditFieldValueChanged, true);
            app.UpperQuantileEditField.Value = 0.8;
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.SaveandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandExitButtonPushed, true);
            app.SaveandExitButton.Text = 'Save and Exit';
            app.SelectedInputDropDownLabel = uilabel(app.UIFigure);
            app.SelectedInputDropDownLabel.Text = 'Selected Input';
            app.SelectedInputDropDown = uidropdown(app.UIFigure);
            app.SelectedInputDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedInputDropDownValueChanged, true);
            app.PredictionspacedivisionsEditFieldLabel = uilabel(app.UIFigure);
            app.PredictionspacedivisionsEditFieldLabel.Text = 'Prediction space divisions';
            app.PredictionspacedivisionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PredictionspacedivisionsEditField.Limits = [2 100];
            app.PredictionspacedivisionsEditField.ValueChangedFcn = createCallbackFcn(app, @PredictionspacedivisionsEditFieldValueChanged, true);
            app.PredictionspacedivisionsEditField.Value = 25;
            app.ToleranceEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranceEditFieldLabel.Text = 'Tolerance ';
            app.ToleranceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToleranceEditField.Limits = [0 15];
            app.ToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @ToleranceEditFieldValueChanged, true);
            app.ToleranceEditField.Value = 3;
            app.nofInterquantileintervalsLabel = uilabel(app.UIFigure);
            app.nofInterquantileintervalsLabel.Text = '(n° of  Interquantile intervals)';
            app.spaceDivisionSlider = uislider(app.UIFigure);
            app.spaceDivisionSlider.Limits = [2 100];
            app.spaceDivisionSlider.ValueChangedFcn = createCallbackFcn(app, @spaceDivisionSliderValueChanged, true);
            app.spaceDivisionSlider.ValueChangingFcn = createCallbackFcn(app, @spaceDivisionSliderValueChanging, true);
            app.spaceDivisionSlider.Value = 25;
            app.lowerQuantileSlider = uislider(app.UIFigure);
            app.lowerQuantileSlider.Limits = [0 0.5];
            app.lowerQuantileSlider.ValueChangedFcn = createCallbackFcn(app, @lowerQuantileSliderValueChanged, true);
            app.lowerQuantileSlider.ValueChangingFcn = createCallbackFcn(app, @lowerQuantileSliderValueChanging, true);
            app.lowerQuantileSlider.Value = 0.2;
            app.upperQuantileSlider = uislider(app.UIFigure);
            app.upperQuantileSlider.Limits = [0.5 1];
            app.upperQuantileSlider.ValueChangedFcn = createCallbackFcn(app, @upperQuantileSliderValueChanged, true);
            app.upperQuantileSlider.ValueChangingFcn = createCallbackFcn(app, @upperQuantileSliderValueChanging, true);
            app.upperQuantileSlider.Value = 0.8;
            app.tolleranceSlider = uislider(app.UIFigure);
            app.tolleranceSlider.Limits = [0 15];
            app.tolleranceSlider.MajorTicks = [0 3 6 9 12 15];
            app.tolleranceSlider.ValueChangedFcn = createCallbackFcn(app, @tolleranceSliderValueChanged, true);
            app.tolleranceSlider.ValueChangingFcn = createCallbackFcn(app, @tolleranceSliderValueChanging, true);
            app.tolleranceSlider.Value = 3;
        end
    end

    methods (Access = public)

        % Construct app
        function app = QRODApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end